classdef Phantoms < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        alpha3                          matlab.ui.control.Label
        alphaLabel_3                    matlab.ui.control.Label
        CalculateAttenuationButton      matlab.ui.control.Button
        SoS_7                           matlab.ui.control.Label
        SoS_6                           matlab.ui.control.Label
        p2                              matlab.ui.control.Label
        p1                              matlab.ui.control.Label
        dBcmLabel                       matlab.ui.control.Label
        alpha2                          matlab.ui.control.Label
        alphaLabel_2                    matlab.ui.control.Label
        dBcmMHzLabel                    matlab.ui.control.Label
        alpha1                          matlab.ui.control.Label
        f0                              matlab.ui.control.NumericEditField
        fMHzLabel                       matlab.ui.control.Label
        alphaLabel                      matlab.ui.control.Label
        SampleDataLabel                 matlab.ui.control.Label
        alpha20fraclogbigfracp2p1bigdLabel  matlab.ui.control.Label
        AttenuationLabel                matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        t2                              matlab.ui.control.Label
        t1                              matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        SoS                             matlab.ui.control.Label
        SoSLabel                        matlab.ui.control.Label
        CalculateSoSButton              matlab.ui.control.Button
        t2Label                         matlab.ui.control.Label
        t1Label                         matlab.ui.control.Label
        distance                        matlab.ui.control.NumericEditField
        dmLabel                         matlab.ui.control.Label
        SoSfracdDeltatLabel             matlab.ui.control.Label
        PeakNumberEditField_2           matlab.ui.control.NumericEditField
        PeakNumberEditField_2Label      matlab.ui.control.Label
        PeakNumberEditField             matlab.ui.control.NumericEditField
        PeakNumberEditFieldLabel        matlab.ui.control.Label
        SpeedofSoundLabel               matlab.ui.control.Label
        DetectPeakButton_2              matlab.ui.control.Button
        SignalPeakSensitivityEditField  matlab.ui.control.NumericEditField
        SignalPeakSensitivityEditFieldLabel  matlab.ui.control.Label
        DetectPeakButton                matlab.ui.control.Button
        BaselinePeakSensitivityEditField  matlab.ui.control.NumericEditField
        BaselinePeakSensitivityEditFieldLabel  matlab.ui.control.Label
        FilterButton_2                  matlab.ui.control.Button
        cutofffreqEditField_2           matlab.ui.control.NumericEditField
        cutofffreqEditField_2Label      matlab.ui.control.Label
        cutofffreqEditField             matlab.ui.control.NumericEditField
        cutofffreqEditFieldLabel        matlab.ui.control.Label
        FilterButton                    matlab.ui.control.Button
        SampleSignalButton              matlab.ui.control.Button
        BaselineSignalButton            matlab.ui.control.Button
        signalAxes                      matlab.ui.control.UIAxes
        blAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        blTT % Description
        blTTFilt % Description
        blTable % Description
        signalTT % Description
        signalTTFilt % Description
        signalTable % Description
        pksbl
        locbl
        pkssignal
        locsignal
        SoSValue
    end
       
    methods (Access = private)
        
        function results = loadblAxes(app,blname)
            opts = delimitedTextImportOptions("NumVariables", 5);

            % Specify range and delimiter
            opts.DataLines = [2, Inf];
            opts.Delimiter = ",";
            
            % Specify column names and types
            opts.VariableNames = ["X", "CH1", "CH2", "Start", "Increment"];
            opts.VariableTypes = ["double", "double", "double", "string", "string"];
            
            % Specify file level properties
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            
            % Specify variable properties
            opts = setvaropts(opts, ["Start", "Increment"], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, ["Start", "Increment"], "EmptyFieldRule", "auto");

            app.blTable= readtable(blname, opts);
            
            StartVar=seconds(str2double(app.blTable.Start(1)));
            TimeStepVar=seconds(str2double(app.blTable.Increment(1)));
            app.blTT = array2timetable(app.blTable.CH2(2:end),'TimeStep',TimeStepVar,'StartTime',StartVar);
            plot(app.blAxes,app.blTT.Time,app.blTT.Var1,"LineWidth",1.5)
            clear opts;
        end

        function results = loadsignalAxes(app,signalname)
            opts = delimitedTextImportOptions("NumVariables", 5);

            % Specify range and delimiter
            opts.DataLines = [2, Inf];
            opts.Delimiter = ",";
            
            % Specify column names and types
            opts.VariableNames = ["X", "CH1", "CH2", "Start", "Increment"];
            opts.VariableTypes = ["double", "double", "double", "string", "string"];
            
            % Specify file level properties
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            
            % Specify variable properties
            opts = setvaropts(opts, ["Start", "Increment"], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, ["Start", "Increment"], "EmptyFieldRule", "auto");

            app.signalTable= readtable(signalname, opts);
            
            StartVar=seconds(str2double(app.signalTable.Start(1)));
            TimeStepVar=seconds(str2double(app.signalTable.Increment(1)));
            app.signalTT = array2timetable(app.signalTable.CH2(2:end),'TimeStep',TimeStepVar,'StartTime',StartVar);
            plot(app.signalAxes,app.signalTT.Time,app.signalTT.Var1,"LineWidth",1.5)
            clear opts;
        end
        
        function results = filterblAxes(app,cut_off_freq)
            fs=1/str2double(app.blTable.Increment(1));
            y = lowpass(app.blTT.Var1,cut_off_freq,fs);
            app.blTTFilt=app.blTT
            app.blTTFilt.Var1=y
            plot(app.blAxes,app.blTT.Time,app.blTT.Var1,"LineWidth",1)
            hold(app.blAxes, 'on')
            plot(app.blAxes,app.blTT.Time,y,"LineWidth",2);
            hold(app.blAxes, 'off');
            legend(app.blAxes,'Original','Filtered');
        end

        function results = filtersignalAxes(app,cut_off_freq)
            fs=1/str2double(app.signalTable.Increment(1));
            y = lowpass(app.signalTT.Var1,cut_off_freq,fs);
            app.signalTTFilt=app.signalTT;
            app.signalTTFilt.Var1=y;
            plot(app.signalAxes,app.signalTT.Time,app.signalTT.Var1,"LineWidth",1);
            hold(app.signalAxes, 'on');
            plot(app.signalAxes,app.signalTT.Time,y,"LineWidth",2);
            hold(app.signalAxes, 'off');
            legend(app.signalAxes,'Original','Filtered');
        end
        
        function results = peakbl(app,sensitivity,number)
            [pksbl, locbl]=findpeaks(app.blTTFilt.Var1,'MinPeakProminence',sensitivity);
            app.pksbl=pksbl(number);
            app.locbl=app.blTTFilt.Time(locbl(number));
            plot(app.blAxes,app.blTT.Time,app.blTT.Var1,"LineWidth",1);
            hold(app.blAxes, 'on');
            plot(app.blAxes,app.blTTFilt.Time,app.blTTFilt.Var1,"LineWidth",2);
            plot(app.blAxes,app.locbl,app.pksbl,'o');
            xline(app.blAxes,app.locbl,'--');
            yline(app.blAxes,app.pksbl,'--');
            hold(app.blAxes, 'off');
            legend(app.blAxes,'Original','Filtered','Max Peak');
            plot(app.signalAxes,app.signalTT.Time,app.signalTT.Var1,"LineWidth",1);
            hold(app.signalAxes, 'on');
            plot(app.signalAxes,app.signalTTFilt.Time,app.signalTTFilt.Var1,"LineWidth",2);
            xline(app.signalAxes,app.locbl,'--');
            hold(app.signalAxes, 'off');
            legend(app.signalAxes,'Original','Filtered');
            app.t1.Text=string(app.locbl);
            app.p1.Text=string(app.pksbl);
        end

        function results = peaksignal(app,sensitivity,number)
            [pkssignal, locsignal]=findpeaks(app.signalTTFilt.Var1,'MinPeakProminence',sensitivity);
            app.pkssignal=pkssignal(number);
            app.locsignal=app.signalTTFilt.Time(locsignal(number));
            plot(app.blAxes,app.blTT.Time,app.blTT.Var1,"LineWidth",1);
            hold(app.blAxes, 'on');
            plot(app.blAxes,app.blTTFilt.Time,app.blTTFilt.Var1,"LineWidth",2);
            plot(app.blAxes,app.locbl,app.pksbl,'o');
            xline(app.blAxes,app.locbl,'--');
            yline(app.blAxes,app.pksbl,'--');
            xline(app.blAxes,app.locsignal,'--');
            hold(app.blAxes, 'off');
            legend(app.blAxes,'Original','Filtered','Max Peak');
            plot(app.signalAxes,app.signalTT.Time,app.signalTT.Var1,"LineWidth",1);
            hold(app.signalAxes, 'on');
            plot(app.signalAxes,app.signalTTFilt.Time,app.signalTTFilt.Var1,"LineWidth",2);
            plot(app.signalAxes,app.locsignal,app.pkssignal,'o');
            xline(app.signalAxes,app.locbl,'--');
            xline(app.signalAxes,app.locsignal,'--');
            yline(app.signalAxes,app.pkssignal,'--');
            hold(app.signalAxes, 'off');
            legend(app.signalAxes,'Original','Filtered','Max Peak');
            app.t2.Text=string(app.locsignal);
            app.p2.Text=string(app.pkssignal);
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BaselineSignalButton
        function BaselineSignalButtonPushed(app, event)
            % Display uigetfile dialog
            filterfile = {'*.csv'};
            [blfile,blpath] = uigetfile(filterfile);

            % Make sure user didn't cancel uigetfile dialog 
            if (ischar(path))
               blname = [blpath blfile];
               loadblAxes(app,blname);
            end
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            cut_off_freq=get(app.cutofffreqEditField,'Value');
            filterblAxes(app,cut_off_freq*1000000);
        end

        % Button pushed function: SampleSignalButton
        function SampleSignalButtonPushed(app, event)
            % Display uigetfile dialog
            filterfile = {'*.csv'};
            [signalfile,signalpath] = uigetfile(filterfile);

            % Make sure user didn't cancel uigetfile dialog 
            if (ischar(path))
               signalname = [signalpath signalfile];
               loadsignalAxes(app,signalname);
            end
        end

        % Button pushed function: FilterButton_2
        function FilterButton_2Pushed(app, event)
            cut_off_freq=get(app.cutofffreqEditField_2,'Value');
            filtersignalAxes(app,cut_off_freq*1000000);
        end

        % Button pushed function: DetectPeakButton
        function DetectPeakButtonPushed(app, event)
            peakSensitivity=get(app.BaselinePeakSensitivityEditField,'Value');
            peakNumber=get(app.PeakNumberEditField,'Value');
            peakbl(app,peakSensitivity,peakNumber);
        end

        % Button pushed function: DetectPeakButton_2
        function DetectPeakButton_2Pushed(app, event)
            peakSensitivity=get(app.SignalPeakSensitivityEditField,'Value');
            peakNumber=get(app.PeakNumberEditField_2,'Value');
            peaksignal(app,peakSensitivity,peakNumber);
        end

        % Button pushed function: CalculateSoSButton
        function CalculateSoSButtonPushed(app, event)
            app.SoSValue=get(app.distance,'Value')/(time2num(app.locsignal,"seconds")-time2num(app.locbl,"seconds"));
            app.SoS.Text=string(app.SoSValue);
        end

        % Button pushed function: CalculateAttenuationButton
        function CalculateAttenuationButtonPushed(app, event)
            app.alpha1.Text=string(20*(log10(app.pksbl/(app.pkssignal)))/(get(app.distance,'Value')*100)/(get(app.f0,'Value')));
            app.alpha2.Text=string(20*(log10(app.pksbl/(app.pkssignal)))/(get(app.distance,'Value')*100));
            app.alpha3.Text=string(20*(log10(app.pksbl/(app.pkssignal))));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.blAxes = uiaxes(app.UIFigure);
            title(app.blAxes, 'Baseline Signal')
            xlabel(app.blAxes, 'Time ')
            ylabel(app.blAxes, 'Voltage')
            zlabel(app.blAxes, 'Z')
            app.signalAxes = uiaxes(app.UIFigure);
            title(app.signalAxes, 'Sample Signal')
            xlabel(app.signalAxes, 'Time')
            ylabel(app.signalAxes, 'Voltage')
            zlabel(app.signalAxes, 'Z')
            app.BaselineSignalButton = uibutton(app.UIFigure, 'push');
            app.BaselineSignalButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineSignalButtonPushed, true);
            app.BaselineSignalButton.Text = 'Baseline Signal';
            app.SampleSignalButton = uibutton(app.UIFigure, 'push');
            app.SampleSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SampleSignalButtonPushed, true);
            app.SampleSignalButton.Text = 'Sample Signal';
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.cutofffreqEditFieldLabel = uilabel(app.UIFigure);
            app.cutofffreqEditFieldLabel.Text = 'cutofffreq';
            app.cutofffreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.cutofffreqEditField.Limits = [0.5 10];
            app.cutofffreqEditField.Value = 0.5;
            app.cutofffreqEditField_2Label = uilabel(app.UIFigure);
            app.cutofffreqEditField_2Label.Text = 'cutofffreq';
            app.cutofffreqEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.cutofffreqEditField_2.Limits = [0.5 10];
            app.cutofffreqEditField_2.Value = 0.5;
            app.FilterButton_2 = uibutton(app.UIFigure, 'push');
            app.FilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @FilterButton_2Pushed, true);
            app.FilterButton_2.Text = 'Filter';
            app.BaselinePeakSensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.BaselinePeakSensitivityEditFieldLabel.Text = 'Baseline Peak Sensitivity';
            app.BaselinePeakSensitivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselinePeakSensitivityEditField.Limits = [0 Inf];
            app.BaselinePeakSensitivityEditField.Value = 0.5;
            app.DetectPeakButton = uibutton(app.UIFigure, 'push');
            app.DetectPeakButton.ButtonPushedFcn = createCallbackFcn(app, @DetectPeakButtonPushed, true);
            app.DetectPeakButton.Text = 'Detect Peak';
            app.SignalPeakSensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.SignalPeakSensitivityEditFieldLabel.Text = 'Signal Peak Sensitivity';
            app.SignalPeakSensitivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalPeakSensitivityEditField.Value = 5e-06;
            app.DetectPeakButton_2 = uibutton(app.UIFigure, 'push');
            app.DetectPeakButton_2.ButtonPushedFcn = createCallbackFcn(app, @DetectPeakButton_2Pushed, true);
            app.DetectPeakButton_2.Text = 'Detect Peak';
            app.SpeedofSoundLabel = uilabel(app.UIFigure);
            app.SpeedofSoundLabel.Text = 'Speed of Sound';
            app.PeakNumberEditFieldLabel = uilabel(app.UIFigure);
            app.PeakNumberEditFieldLabel.Text = 'Peak Number';
            app.PeakNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeakNumberEditField.Limits = [1 Inf];
            app.PeakNumberEditField.Value = 1;
            app.PeakNumberEditField_2Label = uilabel(app.UIFigure);
            app.PeakNumberEditField_2Label.Text = 'Peak Number';
            app.PeakNumberEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PeakNumberEditField_2.Limits = [1 Inf];
            app.PeakNumberEditField_2.Value = 1;
            app.SoSfracdDeltatLabel = uilabel(app.UIFigure);
            app.SoSfracdDeltatLabel.Interpreter = 'latex';
            app.SoSfracdDeltatLabel.Text = 'SoS=\frac{d}{\Delta t}';
            app.dmLabel = uilabel(app.UIFigure);
            app.dmLabel.Text = 'd (m) =';
            app.distance = uieditfield(app.UIFigure, 'numeric');
            app.t1Label = uilabel(app.UIFigure);
            app.t1Label.Text = 't1=';
            app.t2Label = uilabel(app.UIFigure);
            app.t2Label.Text = 't2=';
            app.CalculateSoSButton = uibutton(app.UIFigure, 'push');
            app.CalculateSoSButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSoSButtonPushed, true);
            app.CalculateSoSButton.Text = 'Calculate SoS';
            app.SoSLabel = uilabel(app.UIFigure);
            app.SoSLabel.Text = 'SoS=';
            app.SoS = uilabel(app.UIFigure);
            app.SoS.Text = '00000000';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'm/s';
            app.t1 = uilabel(app.UIFigure);
            app.t1.Text = '00000000';
            app.t2 = uilabel(app.UIFigure);
            app.t2.Text = '00000000';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = 's';
            app.AttenuationLabel = uilabel(app.UIFigure);
            app.AttenuationLabel.Text = 'Attenuation';
            app.alpha20fraclogbigfracp2p1bigdLabel = uilabel(app.UIFigure);
            app.alpha20fraclogbigfracp2p1bigdLabel.Interpreter = 'latex';
            app.alpha20fraclogbigfracp2p1bigdLabel.Text = '\alpha=20\frac{log\big(\frac{p1}{p2}\big)}{d \cdot MHz}';
            app.SampleDataLabel = uilabel(app.UIFigure);
            app.SampleDataLabel.Text = 'Sample Data';
            app.alphaLabel = uilabel(app.UIFigure);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '\alpha =';
            app.fMHzLabel = uilabel(app.UIFigure);
            app.fMHzLabel.Text = 'f (MHz) =';
            app.f0 = uieditfield(app.UIFigure, 'numeric');
            app.alpha1 = uilabel(app.UIFigure);
            app.alpha1.Text = '00000000';
            app.dBcmMHzLabel = uilabel(app.UIFigure);
            app.dBcmMHzLabel.Text = 'dB/cm/MHz';
            app.alphaLabel_2 = uilabel(app.UIFigure);
            app.alphaLabel_2.Interpreter = 'latex';
            app.alphaLabel_2.Text = '\alpha =';
            app.alpha2 = uilabel(app.UIFigure);
            app.alpha2.Text = '00000000';
            app.dBcmLabel = uilabel(app.UIFigure);
            app.dBcmLabel.Text = 'dB/cm';
            app.p1 = uilabel(app.UIFigure);
            app.p1.Text = '00000000';
            app.p2 = uilabel(app.UIFigure);
            app.p2.Text = '00000000';
            app.SoS_6 = uilabel(app.UIFigure);
            app.SoS_6.Text = 'p1=';
            app.SoS_7 = uilabel(app.UIFigure);
            app.SoS_7.Text = 'p2=';
            app.CalculateAttenuationButton = uibutton(app.UIFigure, 'push');
            app.CalculateAttenuationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAttenuationButtonPushed, true);
            app.CalculateAttenuationButton.Text = 'Calculate Attenuation';
            app.alphaLabel_3 = uilabel(app.UIFigure);
            app.alphaLabel_3.Interpreter = 'latex';
            app.alphaLabel_3.Text = '\alpha =';
            app.alpha3 = uilabel(app.UIFigure);
            app.alpha3.Text = '00000000';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '\alpha=20\frac{log\big(\frac{p1}{p2}\big)}{d}';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '\alpha=20log\big(\frac{p1}{p2}\big)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Phantoms

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end